classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        DropDownLabel              matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        ImageSourceDropDownLabel   matlab.ui.control.Label
        ImageSourceDropDown        matlab.ui.control.DropDown
        RunButton                  matlab.ui.control.Button
        ChoosetheimagesourceLabel  matlab.ui.control.Label
        ChoosetheprocessingtechniqueLabel  matlab.ui.control.Label
        DetectingDivingHandGesturesLabel  matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        PredictedHandGestureLabel  matlab.ui.control.Label
        NoteLabel                  matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        CenterPanel                matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        names = categorical({'Air Leaking','Are you Okay','Ascend','Cut the Line','Decompression','Descend','Okay','Stay There','Stop','Turn'}); % Gesture names
        scores;
        pred;
        cam = webcam(1);
    end
 
    methods (Access = private)
        % BW Processing Technique
        function BW(app,image)
            load('GestNet_BW_10.mat'); % Load BW network
            
            % Apply image processing techniques
            imgray = rgb2gray(image);
            imgsize = imresize(imgray, [224, 224]);
            imgbw = imcomplement(imbinarize(imgsize));
            imgbw2 = imfill(imgbw,'holes');
            imgbw3 = bwareaopen(imgbw2, 5000);
            imgbw4 = im2uint8(imgbw3);
             
%             binaryim= logical(imgbw4);
%             blobAnalysis = vision.BlobAnalysis('MinimumBlobArea', 10000);
%             [area, centroid, bbox] = blobAnalysis(binaryim);
%             imnew = insertObjectAnnotation(image,'rectangle',bbox,'hand','LineWidth',3,'FontSize',24);
%             CroppedImage=imcrop(image, bbox);
            
            imshow(imgbw4,'Parent',app.UIAxes);
            
            [w,h] = size(imgbw4);
            imgbw_colour = zeros(w,h,3);
            
            for i = 1:3
                imgbw_colour(:,:,i) = imgbw4;
            end
            
            imgbw_colour = imgbw_colour*255;
            
            [app.pred,app.scores] = classify(GestNet_BW_10,imgbw_colour);
            bar(app.names,app.scores,'Parent',app.UIAxes2);
        end
        
        % Edge Detection Processing Technique
        function edge_detect(app,image)
            load('GestNet_Edge_10.mat') % Load edge detection network
            
            imgray = rgb2gray(image);
            imgsize = imresize(imgray, [224, 224]);
            imgedge1 = edge(imgsize,'sobel');
            imgedge2 = im2uint8(imgedge1);
            imshow(imgedge2,'Parent',app.UIAxes);
            
            [w,h] = size(imgedge2);
            imgedge_colour = zeros(w,h,3);
            
            for i = 1:3
                imgedge_colour(:,:,i) = imgedge2;
            end
            
            imgedge_colour = imgedge_colour*255;
            
            [app.pred,app.scores] = classify(GestNet_Edge_10,imgedge_colour);
            bar(app.names,app.scores,'Parent',app.UIAxes2);
        end
        
        % Unfiltered Technique
        function unfiltered(app,image)
            load('GestNet_Unfiltered_10.mat') % Load unfiltered network
            imgsize = imresize(image, [224, 224]);
            
            imshow(imgsize,'Parent',app.UIAxes);
            
            [app.pred,app.scores] = classify(GestNet_Unfiltered_10,imgsize);
            bar(app.names,app.scores,'Parent',app.UIAxes2);
        end
        
        function prediction(app,scores)
            if all(app.scores(:)<0.5)
                none = char('No gesture detected');
                app.Label_2.Text = none;
            else
                app.Label_2.Text = app.pred;
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
% Snapshot
    if strcmpi('Snapshot',app.ImageSourceDropDown.Value)
        image = snapshot(app.cam);
        if strcmpi('BW',app.DropDown.Value)
            BW(app,image);
            prediction(app,app.scores);
        end
            
        if strcmpi('Edge Detection',app.DropDown.Value)
            edge_detect(app,image);
            prediction(app,app.scores);
        end
        
        if strcmpi('Unfiltered',app.DropDown.Value) % Unfiltered image
            unfiltered(app,image); % Apply unfiltered function with input image
            prediction(app,app.scores);
        end
        
    end
    
% Saved Image
    if strcmpi('Saved Image',app.ImageSourceDropDown.Value)
        [file,path] = uigetfile({'*.jpg','*.png'});
        image = fullfile(path,file);
        image = imread(image);
        if strcmpi('BW',app.DropDown.Value)
            BW(app,image);
            prediction(app,app.scores);
        end
            
        if strcmpi('Edge Detection',app.DropDown.Value)
            edge_detect(app,image);
            prediction(app,app.scores);
        end
        
        if strcmpi('Unfiltered',app.DropDown.Value) % Unfiltered image
            unfiltered(app,image); % Apply unfiltered function with input image
            prediction(app,app.scores);
        end
    end
    
% Saved Video
    if strcmpi('Saved Video',app.ImageSourceDropDown.Value)
        [file,path] = uigetfile({'*.avi','*.mp4'});
        fileloc = fullfile(path,file);
        video = VideoReader(fileloc);
        for i = 1:400
            image = readFrame(video);
            if strcmpi('BW',app.DropDown.Value)
                BW(app,image);
                prediction(app,app.scores);
            end
                
            if strcmpi('Edge Detection',app.DropDown.Value)
                edge_detect(app,image);
                prediction(app,app.scores);
            end
            
            if strcmpi('Unfiltered',app.DropDown.Value) % Unfiltered image
                unfiltered(app,image); % Apply unfiltered function with input image
                prediction(app,app.scores);
            end
            pause (0.5)
        end
    end

% Live Webcam
    while strcmpi('Live Webcam',app.ImageSourceDropDown.Value)
        while strcmpi('BW',app.DropDown.Value) % BW Technique used
            image = snapshot(app.cam);
            BW(app,image); % Apply BW function with input image
            
            prediction(app,app.scores);
            pause(0.5);
        end
            
        while strcmpi('Edge Detection',app.DropDown.Value) % Edge Detection Technique used
            image = snapshot(app.cam); 
            edge_detect(app,image); % Apply edge detection function with input image
                        
            prediction(app,app.scores);  
            pause(0.5);
        end
        
        while strcmpi('Unfiltered',app.DropDown.Value) % Unfiltered image
            image = snapshot(app.cam);
            unfiltered(app,image); % Apply unfiltered function with input image
                   
            prediction(app,app.scores);
            pause(0.5);
        end
    end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {597, 597, 597};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {597, 597};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'BW', 'Edge Detection', 'Unfiltered', ''};
            app.DropDown.ItemsData = {'BW', 'Edge Detection', 'Unfiltered'};
            app.DropDown.Value = 'Edge Detection';
            app.ImageSourceDropDownLabel = uilabel(app.LeftPanel);
            app.ImageSourceDropDownLabel.Text = 'Image Source';
            app.ImageSourceDropDown = uidropdown(app.LeftPanel);
            app.ImageSourceDropDown.Items = {'Snapshot', 'Live Webcam', 'Saved Image', 'Saved Video'};
            app.ImageSourceDropDown.ItemsData = {'Snapshot', 'Live Webcam', 'Saved Image', 'Saved Video'};
            app.ImageSourceDropDown.Value = 'Snapshot';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ChoosetheimagesourceLabel = uilabel(app.LeftPanel);
            app.ChoosetheimagesourceLabel.Text = 'Choose the image source:';
            app.ChoosetheprocessingtechniqueLabel = uilabel(app.LeftPanel);
            app.ChoosetheprocessingtechniqueLabel.Text = 'Choose the processing technique:';
            app.DetectingDivingHandGesturesLabel = uilabel(app.LeftPanel);
            app.DetectingDivingHandGesturesLabel.Text = {'Detecting Diving '; 'Hand Gestures'};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = ' ';
            app.PredictedHandGestureLabel = uilabel(app.LeftPanel);
            app.PredictedHandGestureLabel.Text = 'Predicted Hand Gesture:';
            app.NoteLabel = uilabel(app.LeftPanel);
            app.NoteLabel.Text = 'Note:';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'If all gestures have a probability of less than 0.5 the predicted output is: ''No Gesture Detected''.'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Probability Distribution')
            xlabel(app.UIAxes2, 'Gestures')
            ylabel(app.UIAxes2, 'Probability')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Processed Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end